classdef MainScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TabletDefectDetectionSystemUIFigure  matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        PreProcessingTab   matlab.ui.container.Tab
        OriginalVideoUI    matlab.ui.control.UIAxes
        PreVideoUI         matlab.ui.control.UIAxes
        ProcessingTab      matlab.ui.container.Tab
        ProcessUI          matlab.ui.control.UIAxes
        StartButton        matlab.ui.control.Button
        PostProcessingTab  matlab.ui.container.Tab
        Image              matlab.ui.control.Image
        NameLabel          matlab.ui.control.Label
        WorkIDLabel        matlab.ui.control.Label
        LogOutButton       matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        UploadVideoButton  matlab.ui.control.Button
        LiveVideoButton    matlab.ui.control.Button
        CloseVideoButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        cam;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             warning('off','MATLAB:subscripting:noSubscriptsSpecified');
            app.CloseVideoButton.Visible = 'off';
            app.LiveVideoButton.Visible = 'on';
            global fasterRCNN;
            fasterRCNN = load('fasterRCNN.mat');
        end

        % Button pushed function: UploadVideoButton
        function UploadVideoButtonPushed(app, event)
            global video;
            [filename ,pathname]=uigetfile({'*.*'},'File Selector');
            fullpathname=strcat(pathname,filename);
            
            video = VideoReader(fullpathname);
%           nFrames = video.Duration * video.FrameRate;
            
            nFrames = video.NumberOfFrames;
                
               
                for i=30:nFrames
                    img = read(video, i);
                    
                    GS = rgb2gray(img);  
                    frame = imresize(GS, [400 450]);
                    
                    imshow(img,'Parent',app.OriginalVideoUI);
                    imshow(frame,'Parent',app.PreVideoUI);
                      
                    drawnow
                end
                release(video);
        end

        % Button pushed function: LiveVideoButton
        function LiveVideoButtonPushed(app, event)
          
          app.CloseVideoButton.Visible = 'on';
          app.LiveVideoButton.Visible = 'off';
          
          app.cam = webcam;
          frame = snapshot(app.cam);
          
          img = image(app.OriginalVideoUI, zeros(size(frame), 'uint8'));
          app.OriginalVideoUI.Position = [24, 25, 919, 371];
          app.PreVideoUI.Visible = 'off';
          axis(app.OriginalVideoUI, 'image');
          preview(app.cam, img);
          
%           vid1 = videoinput('winvideo', 1);
%           frame1 = snapshot(vid1);
%           
%           img1 = image(app.PreVideoUI, zeros(size(frame1), 'uint8'));
%           axis(app.PreVideoUI, 'image');
%           preview(vid1, img1);
          
        
%           pause;
        end

        % Button pushed function: CloseVideoButton
        function CloseVideoButtonPushed(app, event)
        
            app.LiveVideoButton.Visible = 'on';
            app.CloseVideoButton.Visible = 'off';   
            closePreview(app.cam); 
            delete (app.cam);
            
            cla(app.OriginalVideoUI, 'reset');
            app.OriginalVideoUI.Position = [24, 25, 460, 371];
            app.OriginalVideoUI.Title.String = "Original Video";
            app.OriginalVideoUI.XLabel.String = "X";
            app.OriginalVideoUI.YLabel.String = "Y";
            
            app.PreVideoUI.Visible = 'on';
            app.PreVideoUI.Position = [483, 25, 460, 371];
            app.PreVideoUI.Title.String = "Pre-Processed Video";
            app.PreVideoUI.XLabel.String = "X";
            app.PreVideoUI.YLabel.String = "Y";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           global fasterRCNN video frame;
           
           GetFrame = [];
           nFrames = video.NumberOfFrames;
           for j=1:nFrames
               if mod(j, 30)==0
                   GetFrame=[GetFrame,j];
               end
           end
           
           app.StartButton.Text = 'Pause';
           
           model = fasterRCNN.fasterRcnn;
           
           nFrames = video.NumberOfFrames;
           
                
           for i=30:nFrames
               img = read(video, i);
               GS = rgb2gray(img);  
               frame = imresize(GS, [400 450]);
               if ismember(i, GetFrame)           
                   [bbox, score, label] = detect(model, frame, 'Threshold', 0.88, 'ExecutionEnvironment', 'gpu'); 
                   [selectedBbox , selectedScore, selectedLabels] = selectStrongestBboxMulticlass(bbox, score , label, 'OverlapThreshold', 0);
                   annotations = string(selectedLabels) + ": " + string(selectedScore);
                   I = insertObjectAnnotation(frame, 'rectangle', selectedBbox, cellstr(annotations));
                   frame = I;
                   imshow(frame, 'Parent', app.ProcessUI);
               end     
                      
               drawnow
           end
        end

        % Size changed function: ProcessingTab
        function ProcessingTabSizeChanged(app, event)
            position = app.ProcessingTab.Position;
            global flag;
            flag = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TabletDefectDetectionSystemUIFigure = uifigure('Visible', 'off');
            app.TabletDefectDetectionSystemUIFigure.Name = 'Tablet Defect Detection System';
            app.TabGroup = uitabgroup(app.TabletDefectDetectionSystemUIFigure);
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.OriginalVideoUI = uiaxes(app.PreProcessingTab);
            title(app.OriginalVideoUI, 'Original Video')
            xlabel(app.OriginalVideoUI, 'X')
            ylabel(app.OriginalVideoUI, 'Y')
            app.PreVideoUI = uiaxes(app.PreProcessingTab);
            title(app.PreVideoUI, 'Pre-Processed Video')
            xlabel(app.PreVideoUI, 'X')
            ylabel(app.PreVideoUI, 'Y')
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.SizeChangedFcn = createCallbackFcn(app, @ProcessingTabSizeChanged, true);
            app.ProcessingTab.Title = 'Processing';
            app.ProcessUI = uiaxes(app.ProcessingTab);
            title(app.ProcessUI, 'Processing')
            xlabel(app.ProcessUI, 'X')
            ylabel(app.ProcessUI, 'Y')
            app.StartButton = uibutton(app.ProcessingTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PostProcessingTab = uitab(app.TabGroup);
            app.PostProcessingTab.Title = 'Post-Processing';
            app.Image = uiimage(app.TabletDefectDetectionSystemUIFigure);
            app.NameLabel = uilabel(app.TabletDefectDetectionSystemUIFigure);
            app.NameLabel.Text = 'Name:';
            app.WorkIDLabel = uilabel(app.TabletDefectDetectionSystemUIFigure);
            app.WorkIDLabel.Text = 'Work ID:';
            app.LogOutButton = uibutton(app.TabletDefectDetectionSystemUIFigure, 'push');
            app.LogOutButton.Text = 'Log Out';
            app.Panel = uipanel(app.TabletDefectDetectionSystemUIFigure);
            app.UploadVideoButton = uibutton(app.Panel, 'push');
            app.UploadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton.Text = 'Upload Video';
            app.LiveVideoButton = uibutton(app.Panel, 'push');
            app.LiveVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LiveVideoButtonPushed, true);
            app.LiveVideoButton.Text = 'Live Video';
            app.CloseVideoButton = uibutton(app.Panel, 'push');
            app.CloseVideoButton.ButtonPushedFcn = createCallbackFcn(app, @CloseVideoButtonPushed, true);
            app.CloseVideoButton.Visible = 'off';
            app.CloseVideoButton.Text = 'Close Video';
            % Show the figure after all components are created
            app.TabletDefectDetectionSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TabletDefectDetectionSystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TabletDefectDetectionSystemUIFigure)
        end
    end
end